classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        EqualizerTab                    matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        SelectFileButton                matlab.ui.control.Button
        Panel_3                         matlab.ui.container.Panel
        StopAudioButton                 matlab.ui.control.Button
        FilterTypeDropDown              matlab.ui.control.DropDown
        FilterTypeDropDownLabel         matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        OutputSampleRateEditField       matlab.ui.control.NumericEditField
        OutputSampleRateEditFieldLabel  matlab.ui.control.Label
        FilterOrder110EditField         matlab.ui.control.NumericEditField
        FilterOrder110EditFieldLabel    matlab.ui.control.Label
        EditField_10                    matlab.ui.control.NumericEditField
        EditField_9                     matlab.ui.control.NumericEditField
        Slider_10                       matlab.ui.control.Slider
        Slider_9                        matlab.ui.control.Slider
        EditField_8                     matlab.ui.control.NumericEditField
        Slider_8                        matlab.ui.control.Slider
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        Panel                           matlab.ui.container.Panel
        CustomButton                    matlab.ui.control.StateButton
        SaveButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        FIlterStructureButtonGroup      matlab.ui.container.ButtonGroup
        FIRButton                       matlab.ui.control.RadioButton
        IIRButton                       matlab.ui.control.RadioButton
        PlayProcessedButton             matlab.ui.control.Button
        PlayOriginalButton              matlab.ui.control.Button
        ApplyButton                     matlab.ui.control.Button
        FlieNameTextArea                matlab.ui.control.TextArea
        Slider_7                        matlab.ui.control.Slider
        Slider_6                        matlab.ui.control.Slider
        Slider_5                        matlab.ui.control.Slider
        Slider_4                        matlab.ui.control.Slider
        Slider_3                        matlab.ui.control.Slider
        Slider_2                        matlab.ui.control.Slider
        Slider                          matlab.ui.control.Slider
        AnalyticsTab                    matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        GenerateResamplingAnalysisButton  matlab.ui.control.Button
        GenerateBand10AnalysisButton    matlab.ui.control.Button
        GenerateBand9AnalysisButton     matlab.ui.control.Button
        GenerateBand8AnalysisButton     matlab.ui.control.Button
        GenerateBand7AnalysisButton     matlab.ui.control.Button
        GenerateBand4AnalysisButton     matlab.ui.control.Button
        GenerateBand5AnalysisButton     matlab.ui.control.Button
        GenerateBand6AnalysisButton     matlab.ui.control.Button
        GenerateBand3AnalysisButton     matlab.ui.control.Button
        GenerateBand2AnalysisButton     matlab.ui.control.Button
        GenerateBand1AnalysisButton     matlab.ui.control.Button
        GenerateSignalComparisonsButton  matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        Gains = zeros(1,10);
        fileName = "test.wav"
        originalSignal
        updatedSignal
        outputSignal
        Fs
        Fs_out
        t
        sliderArray
        sliderLabelArray
        generateAnalysisArray
        sliderCount = 7
        CustomMode = 0 % Description
        filters = {}
        filterType = 'IIR' % Description
        AudioPlayer 
    end
    
    methods (Access = private)
        
        function analyzeBand(app, bandIndex)
            if isempty(app.filters) || bandIndex > length(app.filters)
                uialert(app.UIFigure, 'Please apply the EQ first.', 'No Filter');
                return;
            end
        
            f      = app.filters{bandIndex};
            b      = f.b;
            a      = f.a;
            f_low  = f.low;
            f_high = f.high;
        
            isFIR = strcmp(app.filterType, 'FIR');   % ← just use app.filterType
        
            if isFIR
                filter_order = length(b) - 1;
            else
                filter_order = length(a) - 1;
            end
        
            n_pts     = 1024;
            n_samples = max(200, length(b) * 2);
        
            figure('Name', sprintf('Band %d: %d-%d Hz', bandIndex, f_low, f_high));
        
            % ── 1. Magnitude Response ─────────────────────────────────
            subplot(2,3,1);
            [H, freq] = freqz(b, a, n_pts, app.Fs);
            plot(freq, 20*log10(abs(H) + 1e-10));
            title('Magnitude Response');
            xlabel('Frequency (Hz)'); ylabel('Magnitude (dB)');
            grid on;
        
            % ── 2. Phase Response ─────────────────────────────────────
            subplot(2,3,2);
            if isFIR
                plot(freq, unwrap(angle(H)) * (180/pi));
            else
                plot(freq, angle(H) * (180/pi));
            end
            title('Phase Response');
            xlabel('Frequency (Hz)'); ylabel('Phase (degrees)');
            grid on;
        
            % ── 3. Impulse Response ───────────────────────────────────
            subplot(2,3,3);
            [h, t_imp] = impz(b, a, n_samples);
            stem(t_imp, h, 'filled', 'MarkerSize', 2);
            title('Impulse Response');
            xlabel('Samples'); ylabel('Amplitude');
            grid on;
            if isFIR
                xline(filter_order, '--r', 'Order', 'LabelVerticalAlignment', 'bottom');
            end
        
            % ── 4. Step Response ──────────────────────────────────────
            subplot(2,3,4);
            [s, t_step] = stepz(b, a, n_samples);
            stem(t_step, s, 'filled', 'MarkerSize', 2);
            title('Step Response');
            xlabel('Samples'); ylabel('Amplitude');
            grid on;
            if isFIR
                yline(sum(b), '--r', 'Steady state');
            end
        
            % ── 5. Pole-Zero Plot ─────────────────────────────────────
            subplot(2,3,5);
            zplane(b, a);
            if isFIR
                title('Pole-Zero Plot (all poles at origin)');
            else
                title('Pole-Zero Plot');
            end
        
            % ── 6. Filter Info ────────────────────────────────────────
            subplot(2,3,6);
            axis off;
            text(0.5, 0.85, sprintf('Band %d', bandIndex), ...
                'HorizontalAlignment', 'center', 'FontSize', 13, 'FontWeight', 'bold');
            text(0.5, 0.68, sprintf('%d Hz  —  %d Hz', f_low, f_high), ...
                'HorizontalAlignment', 'center', 'FontSize', 11);
            text(0.5, 0.52, sprintf('Structure: %s', app.filterType), ...
                'HorizontalAlignment', 'center', 'FontSize', 11);
            text(0.5, 0.36, sprintf('Filter Order: %d', filter_order), ...
                'HorizontalAlignment', 'center', 'FontSize', 11);
        
            if isFIR
                text(0.5, 0.20, sprintf('Coefficients: %d', length(b)), ...
                    'HorizontalAlignment', 'center', 'FontSize', 11);
                text(0.5, 0.06, 'Linear phase: Yes', ...
                    'HorizontalAlignment', 'center', 'FontSize', 10, 'Color', [0 0.5 0]);
            else
                poles = roots(a);
                if all(abs(poles) < 1)
                    stability_str = 'Stable';
                    col = [0 0.5 0];
                else
                    stability_str = 'UNSTABLE';
                    col = [1 0 0];
                end
                text(0.5, 0.20, sprintf('Stability: %s', stability_str), ...
                    'HorizontalAlignment', 'center', 'FontSize', 10, 'Color', col);
                text(0.5, 0.06, 'Linear phase: No', ...
                    'HorizontalAlignment', 'center', 'FontSize', 10, 'Color', [0.7 0 0]);
            end
        
            sgtitle(sprintf('Band %d Analysis: %d-%d Hz  |  %s', ...
                bandIndex, f_low, f_high, app.filterType));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.sliderArray = [app.Slider, app.Slider_2, app.Slider_3, ...
                       app.Slider_4, app.Slider_5, app.Slider_6, app.Slider_7, app.Slider_8, app.Slider_9, app.Slider_10];
            app.sliderLabelArray = [app.EditField, app.EditField_2, app.EditField_3, ...
                app.EditField_4, app.EditField_5, app.EditField_6, app.EditField_7, app.EditField_8, app.EditField_9, app.EditField_10];
            
            app.generateAnalysisArray = [app.GenerateBand1AnalysisButton,app.GenerateBand2AnalysisButton,app.GenerateBand3AnalysisButton, ...
                app.GenerateBand4AnalysisButton,app.GenerateBand5AnalysisButton,app.GenerateBand6AnalysisButton,app.GenerateBand7AnalysisButton, ...
                app.GenerateBand8AnalysisButton,app.GenerateBand9AnalysisButton,app.GenerateBand10AnalysisButton];

            app.FilterTypeDropDown.Items = {'Butterworth' 'Chebyshev I' 'Chebyshev II'};
                app.FilterTypeDropDown.Value = 'Butterworth';
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.Gains(str2double(app.Slider.Tag)) = app.Slider.Value;
        end

        % Callback function
        function Slider_2ValueChanged(app, event)
            app.Gains(str2double(app.Slider_2.Tag)) = app.Slider_2.Value;
        end

        % Callback function
        function Slider_3ValueChanged(app, event)
            app.Gains(str2double(app.Slider_3.Tag)) = app.Slider_3.Value;
        end

        % Callback function
        function Slider_4ValueChanged(app, event)
            app.Gains(str2double(app.Slider_4.Tag)) = app.Slider_4.Value;
        end

        % Callback function
        function Slider_5ValueChanged(app, event)
            app.Gains(str2double(app.Slider_5.Tag)) = app.Slider_5.Value;
            
        end

        % Callback function
        function Slider_6ValueChanged(app, event)
            app.Gains(str2double(app.Slider_6.Tag)) = app.Slider_6.Value;
        end

        % Callback function
        function Slider_7ValueChanged(app, event)
            app.Gains(str2double(app.Slider_7.Tag)) = app.Slider_7.Value;
        end

        % Value changed function: FlieNameTextArea
        function FlieNameTextAreaValueChanged(app, event)
            app.fileName = app.FlieNameTextArea.Value{1};
            
            appDir = fileparts(which('app1.mlapp'));
            fullPath = fullfile(appDir, app.fileName);

            [app.originalSignal,app.Fs] = audioread(fullPath);
            %app.InputSampleRateLabel.Text = sprintf('Input Sample Rate\n%d Hz', app.Fs);
            app.OutputSampleRateEditField.Value = app.Fs;

        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            bands = zeros(1,app.sliderCount + 1);
            for i = 1:app.sliderCount
                bands(i+1) = app.sliderLabelArray(i).Value;
                app.Gains(i) = app.sliderArray(i).Value;
            end

            if app.IIRButton.Value
                app.filterType = 'IIR';
            else
                app.filterType = 'FIR';
            end
            try
                [app.originalSignal,app.updatedSignal,app.Fs,app.t,app.filters] = Equalize(app.fileName,bands,app.Gains,app.FilterOrder110EditField.Value,app.filterType,app.FilterTypeDropDown.Value);
            catch e
                uialert(app.UIFigure, e.message, 'Error');
            end

        end

        % Button pushed function: PlayOriginalButton
        function PlayOriginalButtonPushed(app, event)
            % 1. Stop any currently playing audio player
            if ~isempty(app.AudioPlayer)
                stop(app.AudioPlayer);
            end
            
            % 2. Create the audio player object and play it
            app.AudioPlayer = audioplayer(app.originalSignal, app.Fs);
            play(app.AudioPlayer);
        end

        % Button pushed function: PlayProcessedButton
        function PlayProcessedButtonPushed(app, event)
            if ~isempty(app.AudioPlayer)
                stop(app.AudioPlayer);
            end
           
            app.AudioPlayer = audioplayer(app.updatedSignal, app.Fs);
            play(app.AudioPlayer);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.sliderCount = 7;
            bands = [0 100 300 800 2000 5000 10000 20000];
            state = {'off', 'on'};
            for i = 1:7
                app.sliderLabelArray(i).Value = bands(i + 1);
                app.sliderLabelArray(i).Enable = 'on';
                app.sliderLabelArray(i).Editable = state{app.CustomMode + 1};
                app.sliderLabelArray(i).Visible = 'on';
                
                app.sliderArray(i).Visible = 'on';
                app.sliderArray(i).Enable = 'on';
                app.sliderArray(i).Value = 0;

                app.generateAnalysisArray(i).Visible = 'on';

            end
            for i = 8:10
                app.sliderLabelArray(i).Enable = 'off';
                app.sliderLabelArray(i).Editable = 'off';
                app.sliderLabelArray(i).Visible = 'off';
                app.sliderLabelArray(i).Value = 20000;

                app.sliderArray(i).Visible = 'off';
                app.sliderArray(i).Enable = 'off';
                app.sliderArray(i).Value = 0;
                
                app.generateAnalysisArray(i).Visible = 'off';
            end
            if app.IIRButton.Value
                app.FilterOrder110EditField.Value = 8;
            else
                app.FilterOrder110EditField.Value = 100;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isempty(app.updatedSignal)
                uialert(app.UIFigure, 'Please apply the EQ first.', 'No Signal');
                return;
            end
            app.outputSignal = app.updatedSignal;
            app.Fs_out = app.Fs;
            if app.OutputSampleRateEditField.Value ~= 0 
                app.Fs_out = app.OutputSampleRateEditField.Value;
                [p, q] = rat(app.Fs_out / app.Fs); 
                app.outputSignal = resample(app.updatedSignal, p, q);
            end
            
            
            audiowrite('output_equalized.wav', app.outputSignal, app.Fs_out);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % the plus button... aka add another slider
            if app.sliderCount == 10 || app.CustomMode == 0
                return
            end

            app.sliderCount = app.sliderCount + 1;

            app.sliderArray(app.sliderCount).Visible = 'on';
            app.sliderArray(app.sliderCount).Enable = 'on';
            app.generateAnalysisArray(app.sliderCount).Visible = 'on';
            
            app.sliderLabelArray(app.sliderCount).Visible = 'on';
            app.sliderLabelArray(app.sliderCount).Editable = 'on';
            app.sliderLabelArray(app.sliderCount).Enable = 'on';

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % the minus button... aka remove a slider
            if app.sliderCount == 5 || app.CustomMode == 0
                return
            end

            app.sliderArray(app.sliderCount).Visible = 'off';
            app.sliderArray(app.sliderCount).Enable = 'off';
            app.generateAnalysisArray(app.sliderCount).Visible = 'off';
            
            app.sliderLabelArray(app.sliderCount).Visible = 'off';
            app.sliderLabelArray(app.sliderCount).Editable = 'off';
            app.sliderLabelArray(app.sliderCount).Enable = 'off';

            app.sliderCount = app.sliderCount - 1;

        end

        % Value changed function: CustomButton
        function CustomButtonValueChanged(app, event)
            value = app.CustomButton.Value;
            if value
                app.CustomMode = 1;
            else
                app.CustomMode = 0;
            end
            
            state = {'off', 'on'};
            for i = 1:10
                app.sliderLabelArray(i).Editable = state{app.CustomMode + 1}; 
            end
            app.FilterOrder110EditField.Editable = state{app.CustomMode + 1};
        end

        % Callback function
        function ApplyButtonPushed2(app, event)
            
        end

        % Button pushed function: GenerateSignalComparisonsButton
        function GenerateSignalComparisonsButtonPushed(app, event)
            if isempty(app.originalSignal)
                uialert(app.UIFigure, 'Please apply the EQ first.', 'No Signal');
                return;
            end
        
            N_fft  = 2^15;
            f_axis = (0:N_fft/2-1) * (app.Fs / N_fft);
        
            X_fft = abs(fft(app.originalSignal,  N_fft));
            Y_fft = abs(fft(app.updatedSignal,   N_fft));
            X_db  = 20*log10(X_fft(1:N_fft/2) + 1e-10);
            Y_db  = 20*log10(Y_fft(1:N_fft/2) + 1e-10);
        
            window_len = round(app.Fs * 0.03);
            overlap    = round(window_len * 0.75);
            n_fft_sg   = 1024;
        
            figure('Name', 'Signal Comparisons', 'NumberTitle', 'off');
        
            % ── 1. Time Domain Original ───────────────────────────────
            subplot(4,2,1);
            plot(app.t, app.originalSignal, 'b', 'LineWidth', 0.5);
            title('Original — Time Domain');
            xlabel('Time (s)'); ylabel('Amplitude');
            xlim([0 min(app.t(end), 2)]);
            grid on;
        
            % ── 2. Time Domain Processed ──────────────────────────────
            subplot(4,2,2);
            plot(app.t, app.updatedSignal, 'r', 'LineWidth', 0.5);
            title('Processed — Time Domain');
            xlabel('Time (s)'); ylabel('Amplitude');
            xlim([0 min(app.t(end), 2)]);
            grid on;
        
            % ── 3. Frequency Domain Original ─────────────────────────
            subplot(4,2,3);
            plot(f_axis, X_db, 'b');
            title('Original — Frequency Domain');
            xlabel('Frequency (Hz)'); ylabel('Magnitude (dB)');
            xlim([0 app.Fs/2]); grid on;
        
            % ── 4. Frequency Domain Processed ────────────────────────
            subplot(4,2,4);
            plot(f_axis, Y_db, 'r');
            title('Processed — Frequency Domain');
            xlabel('Frequency (Hz)'); ylabel('Magnitude (dB)');
            xlim([0 app.Fs/2]); grid on;
        
            % ── 5. PSD Overlay ────────────────────────────────────────
            subplot(4,2,5);
            [pxx_orig, f_psd] = pwelch(app.originalSignal, hamming(1024), 512, 1024, app.Fs);
            [pxx_proc, ~    ] = pwelch(app.updatedSignal,  hamming(1024), 512, 1024, app.Fs);
            plot(f_psd, 10*log10(pxx_orig), 'b', 'DisplayName', 'Original'); hold on;
            plot(f_psd, 10*log10(pxx_proc), 'r', 'DisplayName', 'Processed');
            title('Power Spectral Density');
            xlabel('Frequency (Hz)'); ylabel('Power/Frequency (dB/Hz)');
            xlim([0 app.Fs/2]); grid on; legend;
        
            % ── 6. Frequency Overlay ──────────────────────────────────
            subplot(4,2,6);
            plot(f_axis, X_db, 'b', 'DisplayName', 'Original'); hold on;
            plot(f_axis, Y_db, 'r', 'DisplayName', 'Processed');
            title('Frequency Domain Overlay');
            xlabel('Frequency (Hz)'); ylabel('Magnitude (dB)');
            xlim([0 app.Fs/2]); grid on; legend;
        
            % ── 7. Spectrogram Original ───────────────────────────────
            subplot(4,2,7);
            spectrogram(app.originalSignal, hamming(window_len), overlap, n_fft_sg, app.Fs, 'yaxis');
            title('Original — Spectrogram');
            colormap jet;
        
            % ── 8. Spectrogram Processed ──────────────────────────────
            subplot(4,2,8);
            spectrogram(app.updatedSignal, hamming(window_len), overlap, n_fft_sg, app.Fs, 'yaxis');
            title('Processed — Spectrogram');
            colormap jet;
        
            sgtitle('Signal Comparisons: Original vs Processed');
        end

        % Button pushed function: GenerateBand1AnalysisButton
        function GenerateBand1AnalysisButtonPushed(app, event)
            analyzeBand(app,1);
        end

        % Button pushed function: GenerateBand2AnalysisButton
        function GenerateBand2AnalysisButtonPushed(app, event)
            analyzeBand(app,2);
        end

        % Button pushed function: GenerateBand3AnalysisButton
        function GenerateBand3AnalysisButtonPushed(app, event)
            analyzeBand(app,3);
        end

        % Button pushed function: GenerateBand4AnalysisButton
        function GenerateBand4AnalysisButtonPushed(app, event)
            analyzeBand(app,4);
        end

        % Button pushed function: GenerateBand5AnalysisButton
        function GenerateBand5AnalysisButtonPushed(app, event)
            analyzeBand(app,5);
        end

        % Button pushed function: GenerateBand6AnalysisButton
        function GenerateBand6AnalysisButtonPushed(app, event)
            analyzeBand(app,6);
        end

        % Button pushed function: GenerateBand7AnalysisButton
        function GenerateBand7AnalysisButtonPushed(app, event)
            analyzeBand(app,7);
        end

        % Button pushed function: GenerateBand8AnalysisButton
        function GenerateBand8AnalysisButtonPushed(app, event)
            analyzeBand(app,8);
        end

        % Button pushed function: GenerateBand9AnalysisButton
        function GenerateBand9AnalysisButtonPushed(app, event)
            analyzeBand(app,9);
        end

        % Button pushed function: GenerateBand10AnalysisButton
        function GenerateBand10AnalysisButtonPushed(app, event)
            analyzeBand(app,10);
        end

        % Selection changed function: FIlterStructureButtonGroup
        function FIlterStructureButtonGroupSelectionChanged(app, event)
            %selectedButton = app.FIlterStructureButtonGroup.SelectedObject;
            if app.IIRButton.Value
                app.filterType = 'IIR';
                app.FilterTypeDropDown.Items = {'Butterworth' , 'Chebyshev I', 'Chebyshev II'};
                app.FilterTypeDropDown.Value = 'Butterworth';

                app.FilterOrder110EditField.Limits = [1 10];
                app.FilterOrder110EditField.Value  = 8;
                app.FilterOrder110EditFieldLabel.Text = {'Filter Order'; '(1-10)'};
            else
                app.filterType = 'FIR';
                app.FilterTypeDropDown.Items = {'hamming' , 'hanning', 'blackman'};
                app.FilterTypeDropDown.Value = 'hamming';
                
                app.FilterOrder110EditField.Limits = [10 200];
                app.FilterOrder110EditField.Value  = 100;
                app.FilterOrder110EditFieldLabel.Text = {'Filter Order'; '(10-200)'};
            end
        end

        % Button pushed function: GenerateResamplingAnalysisButton
        function GenerateResamplingAnalysisButtonPushed(app, event)
            if isempty(app.updatedSignal)
                uialert(app.UIFigure, 'Please apply the EQ first.', 'No Signal');
                return;
            end
        
            app.Fs_out = app.OutputSampleRateEditField.Value;
        
            if app.Fs_out == 0
                uialert(app.UIFigure, 'Please enter an output sample rate first.', 'No Sample Rate');
                return;
            end
        
            [p, q] = rat(app.Fs_out / app.Fs);
            y_resampled = resample(app.updatedSignal, p, q);
        
            t_orig     = (0:length(app.updatedSignal)-1) / app.Fs;
            t_resampled = (0:length(y_resampled)-1)      / app.Fs_out;
        
            N = 2^15;
            f_orig      = (0:N/2-1) * (app.Fs  / N);
            f_resampled = (0:N/2-1) * (app.Fs_out  / N);
        
            tmp = abs(fft(app.updatedSignal, N)); X_orig      = 20*log10(tmp(1:N/2) + 1e-10);
            tmp = abs(fft(y_resampled,       N)); X_resampled = 20*log10(tmp(1:N/2) + 1e-10);
        
            figure('Name', 'Resampling Analysis', 'NumberTitle', 'off');
        
            subplot(2,2,1);
            plot(t_orig, app.updatedSignal, 'b', 'LineWidth', 0.5);
            title(sprintf('Original — Fs = %d Hz', app.Fs));
            xlabel('Time (s)'); ylabel('Amplitude');
            xlim([0 min(t_orig(end), 2)]); grid on;
        
            subplot(2,2,2);
            plot(t_resampled, y_resampled, 'r', 'LineWidth', 0.5);
            title(sprintf('Resampled — Fs = %d Hz', app.Fs_out));
            xlabel('Time (s)'); ylabel('Amplitude');
            xlim([0 min(t_resampled(end), 2)]); grid on;
        
            subplot(2,2,3);
            plot(f_orig, X_orig, 'b');
            title(sprintf('Original — Nyquist = %d Hz', app.Fs/2));
            xlabel('Frequency (Hz)'); ylabel('Magnitude (dB)');
            xlim([0 app.Fs/2]); grid on;
        
            subplot(2,2,4);
            plot(f_resampled, X_resampled, 'r');
            title(sprintf('Resampled — Nyquist = %d Hz', app.Fs_out/2));
            xlabel('Frequency (Hz)'); ylabel('Magnitude (dB)');
            xlim([0 app.Fs_out/2]); grid on;
        
            sgtitle(sprintf('Resampling Analysis: %d Hz → %d Hz', app.Fs, app.Fs_out));
        end

        % Button pushed function: StopAudioButton
        function StopAudioButtonPushed(app, event)
            if ~isempty(app.AudioPlayer)
                stop(app.AudioPlayer);
            end
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            % Open file selection dialog
            [file, path] = uigetfile({'*.wav'; '*.mp3'}, 'Select a File');
            figure(app.UIFigure);
            
            % Check if the user selected a file (returns 0 if Cancel is clicked)
            if isequal(file, 0)
               return; 
            end
            
            % Combine path and file name
            app.fileName = fullfile(path, file);
            
            % Optional: Display the path in an Edit Field (if you added one)
            app.FlieNameTextArea.Value = file;

            [app.originalSignal,app.Fs] = audioread(app.fileName);
            %app.InputSampleRateLabel.Text = sprintf('Input Sample Rate\n%d Hz', app.Fs);
            app.OutputSampleRateEditField.Value = app.Fs;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EqualizerTab = uitab(app.TabGroup);
            app.EqualizerTab.Title = 'Equalizer';
            app.Panel_4 = uipanel(app.EqualizerTab);
            app.GridLayout = uigridlayout(app.Panel_4);
            app.Slider = uislider(app.GridLayout);
            app.Slider.Limits = [-20 20];
            app.Slider.MajorTicks = [-20 -12 -6 -3 0 3 6 12 20];
            app.Slider.MajorTickLabels = {'-20', '-12', '-6', '', '0', '', '6', '12', '20'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Tag = '1';
            app.Slider_2 = uislider(app.GridLayout);
            app.Slider_2.Limits = [-20 20];
            app.Slider_2.MajorTicks = [-20 -12 -6 -3 0 3 6 12 20];
            app.Slider_2.MajorTickLabels = {'-20', '-12', '-6', '', '0', '', '6', '12', '20'};
            app.Slider_2.Tag = '2';
            app.Slider_3 = uislider(app.GridLayout);
            app.Slider_3.Limits = [-20 20];
            app.Slider_3.MajorTicks = [-20 -12 -6 -3 0 3 6 12 20];
            app.Slider_3.MajorTickLabels = {'-20', '-12', '-6', '', '0', '', '6', '12', '20'};
            app.Slider_3.Tag = '3';
            app.Slider_4 = uislider(app.GridLayout);
            app.Slider_4.Limits = [-20 20];
            app.Slider_4.MajorTicks = [-20 -12 -6 -3 0 3 6 12 20];
            app.Slider_4.MajorTickLabels = {'-20', '-12', '-6', '', '0', '', '6', '12', '20'};
            app.Slider_4.Tag = '4';
            app.Slider_5 = uislider(app.GridLayout);
            app.Slider_5.Limits = [-20 20];
            app.Slider_5.MajorTicks = [-20 -12 -6 -3 0 3 6 12 20];
            app.Slider_5.MajorTickLabels = {'-20', '-12', '-6', '', '0', '', '6', '12', '20'};
            app.Slider_5.Tag = '5';
            app.Slider_6 = uislider(app.GridLayout);
            app.Slider_6.Limits = [-20 20];
            app.Slider_6.MajorTicks = [-20 -12 -6 -3 0 3 6 12 20];
            app.Slider_6.MajorTickLabels = {'-20', '-12', '-6', '', '0', '', '6', '12', '20'};
            app.Slider_6.Tag = '6';
            app.Slider_7 = uislider(app.GridLayout);
            app.Slider_7.Limits = [-20 20];
            app.Slider_7.MajorTicks = [-20 -12 -6 -3 0 3 6 12 20];
            app.Slider_7.MajorTickLabels = {'-20', '-12', '-6', '', '0', '', '6', '12', '20'};
            app.Slider_7.Tag = '7';
            app.FlieNameTextArea = uitextarea(app.GridLayout);
            app.FlieNameTextArea.ValueChangedFcn = createCallbackFcn(app, @FlieNameTextAreaValueChanged, true);
            app.FlieNameTextArea.Editable = 'off';
            app.FlieNameTextArea.Placeholder = 'test.wav';
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.PlayOriginalButton = uibutton(app.GridLayout, 'push');
            app.PlayOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalButtonPushed, true);
            app.PlayOriginalButton.Text = 'Play Original';
            app.PlayProcessedButton = uibutton(app.GridLayout, 'push');
            app.PlayProcessedButton.ButtonPushedFcn = createCallbackFcn(app, @PlayProcessedButtonPushed, true);
            app.PlayProcessedButton.Text = 'Play Processed';
            app.FIlterStructureButtonGroup = uibuttongroup(app.GridLayout);
            app.FIlterStructureButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FIlterStructureButtonGroupSelectionChanged, true);
            app.FIlterStructureButtonGroup.Title = 'FIlter Structure';
            app.IIRButton = uiradiobutton(app.FIlterStructureButtonGroup);
            app.IIRButton.Text = 'IIR';
            app.IIRButton.Value = true;
            app.FIRButton = uiradiobutton(app.FIlterStructureButtonGroup);
            app.FIRButton.Text = 'FIR';
            app.Panel = uipanel(app.GridLayout);
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '-';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '+';
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CustomButton = uibutton(app.Panel, 'state');
            app.CustomButton.ValueChangedFcn = createCallbackFcn(app, @CustomButtonValueChanged, true);
            app.CustomButton.Text = 'Custom';
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            app.EditField.Limits = [0 20000];
            app.EditField.Editable = 'off';
            app.EditField.Value = 100;
            app.EditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_2.Limits = [0 20000];
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = 300;
            app.EditField_3 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_3.Limits = [0 20000];
            app.EditField_3.Editable = 'off';
            app.EditField_3.Value = 800;
            app.EditField_4 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_4.Limits = [0 20000];
            app.EditField_4.Editable = 'off';
            app.EditField_4.Value = 2000;
            app.EditField_5 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_5.Limits = [0 20000];
            app.EditField_5.Editable = 'off';
            app.EditField_5.Value = 5000;
            app.EditField_6 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_6.Limits = [0 20000];
            app.EditField_6.ValueDisplayFormat = '%.0f';
            app.EditField_6.Editable = 'off';
            app.EditField_6.Value = 10000;
            app.EditField_7 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_7.Limits = [0 20000];
            app.EditField_7.ValueDisplayFormat = '%.0f';
            app.EditField_7.Editable = 'off';
            app.EditField_7.Value = 20000;
            app.Slider_8 = uislider(app.GridLayout);
            app.Slider_8.Limits = [-20 20];
            app.Slider_8.MajorTicks = [-20 -12 -6 -3 0 3 6 12 20];
            app.Slider_8.MajorTickLabels = {'-20', '-12', '-6', '', '0', '', '6', '12', '20'};
            app.Slider_8.Tag = '8';
            app.Slider_8.Enable = 'off';
            app.Slider_8.Visible = 'off';
            app.EditField_8 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_8.Limits = [0 20000];
            app.EditField_8.ValueDisplayFormat = '%.0f';
            app.EditField_8.Editable = 'off';
            app.EditField_8.Enable = 'off';
            app.EditField_8.Visible = 'off';
            app.EditField_8.Value = 20000;
            app.Slider_9 = uislider(app.GridLayout);
            app.Slider_9.Limits = [-20 20];
            app.Slider_9.MajorTicks = [-20 -12 -6 -3 0 3 6 12 20];
            app.Slider_9.MajorTickLabels = {'-20', '-12', '-6', '', '0', '', '6', '12', '20'};
            app.Slider_9.Tag = '9';
            app.Slider_9.Enable = 'off';
            app.Slider_9.Visible = 'off';
            app.Slider_10 = uislider(app.GridLayout);
            app.Slider_10.Limits = [-20 20];
            app.Slider_10.MajorTicks = [-20 -12 -6 -3 0 3 6 12 20];
            app.Slider_10.MajorTickLabels = {'-20', '-12', '-6', '', '0', '', '6', '12', '20'};
            app.Slider_10.Tag = '10';
            app.Slider_10.Enable = 'off';
            app.Slider_10.Visible = 'off';
            app.EditField_9 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_9.Limits = [0 20000];
            app.EditField_9.ValueDisplayFormat = '%.0f';
            app.EditField_9.Editable = 'off';
            app.EditField_9.Enable = 'off';
            app.EditField_9.Visible = 'off';
            app.EditField_9.Value = 20000;
            app.EditField_10 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_10.Limits = [0 20000];
            app.EditField_10.ValueDisplayFormat = '%.0f';
            app.EditField_10.Editable = 'off';
            app.EditField_10.Enable = 'off';
            app.EditField_10.Visible = 'off';
            app.EditField_10.Value = 20000;
            app.Panel_2 = uipanel(app.GridLayout);
            app.FilterOrder110EditFieldLabel = uilabel(app.Panel_2);
            app.FilterOrder110EditFieldLabel.Text = {'Filter Order'; '(1-10)'};
            app.FilterOrder110EditField = uieditfield(app.Panel_2, 'numeric');
            app.FilterOrder110EditField.Limits = [1 10];
            app.FilterOrder110EditField.ValueDisplayFormat = '%.0f';
            app.FilterOrder110EditField.Editable = 'off';
            app.FilterOrder110EditField.Value = 8;
            app.OutputSampleRateEditFieldLabel = uilabel(app.Panel_2);
            app.OutputSampleRateEditFieldLabel.Text = {'Output Sample'; 'Rate'};
            app.OutputSampleRateEditField = uieditfield(app.Panel_2, 'numeric');
            app.OutputSampleRateEditField.ValueDisplayFormat = '%.0f';
            app.Panel_3 = uipanel(app.GridLayout);
            app.FilterTypeDropDownLabel = uilabel(app.Panel_3);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.Panel_3);
            app.StopAudioButton = uibutton(app.Panel_3, 'push');
            app.StopAudioButton.ButtonPushedFcn = createCallbackFcn(app, @StopAudioButtonPushed, true);
            app.StopAudioButton.Text = 'Stop Audio';
            app.SelectFileButton = uibutton(app.GridLayout, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.AnalyticsTab = uitab(app.TabGroup);
            app.AnalyticsTab.Title = 'Analytics';
            app.GridLayout2 = uigridlayout(app.AnalyticsTab);
            app.GenerateSignalComparisonsButton = uibutton(app.GridLayout2, 'push');
            app.GenerateSignalComparisonsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSignalComparisonsButtonPushed, true);
            app.GenerateSignalComparisonsButton.Text = 'Generate Signal Comparisons';
            app.GenerateBand1AnalysisButton = uibutton(app.GridLayout2, 'push');
            app.GenerateBand1AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBand1AnalysisButtonPushed, true);
            app.GenerateBand1AnalysisButton.Text = 'Generate Band1 Analysis';
            app.GenerateBand2AnalysisButton = uibutton(app.GridLayout2, 'push');
            app.GenerateBand2AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBand2AnalysisButtonPushed, true);
            app.GenerateBand2AnalysisButton.Text = 'Generate Band2 Analysis';
            app.GenerateBand3AnalysisButton = uibutton(app.GridLayout2, 'push');
            app.GenerateBand3AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBand3AnalysisButtonPushed, true);
            app.GenerateBand3AnalysisButton.Text = 'Generate Band3 Analysis';
            app.GenerateBand6AnalysisButton = uibutton(app.GridLayout2, 'push');
            app.GenerateBand6AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBand6AnalysisButtonPushed, true);
            app.GenerateBand6AnalysisButton.Text = 'Generate Band6 Analysis';
            app.GenerateBand5AnalysisButton = uibutton(app.GridLayout2, 'push');
            app.GenerateBand5AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBand5AnalysisButtonPushed, true);
            app.GenerateBand5AnalysisButton.Text = 'Generate Band5 Analysis';
            app.GenerateBand4AnalysisButton = uibutton(app.GridLayout2, 'push');
            app.GenerateBand4AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBand4AnalysisButtonPushed, true);
            app.GenerateBand4AnalysisButton.Text = 'Generate Band4 Analysis';
            app.GenerateBand7AnalysisButton = uibutton(app.GridLayout2, 'push');
            app.GenerateBand7AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBand7AnalysisButtonPushed, true);
            app.GenerateBand7AnalysisButton.Text = 'Generate Band7 Analysis';
            app.GenerateBand8AnalysisButton = uibutton(app.GridLayout2, 'push');
            app.GenerateBand8AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBand8AnalysisButtonPushed, true);
            app.GenerateBand8AnalysisButton.Visible = 'off';
            app.GenerateBand8AnalysisButton.Text = 'Generate Band8 Analysis';
            app.GenerateBand9AnalysisButton = uibutton(app.GridLayout2, 'push');
            app.GenerateBand9AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBand9AnalysisButtonPushed, true);
            app.GenerateBand9AnalysisButton.Visible = 'off';
            app.GenerateBand9AnalysisButton.Text = 'Generate Band9 Analysis';
            app.GenerateBand10AnalysisButton = uibutton(app.GridLayout2, 'push');
            app.GenerateBand10AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBand10AnalysisButtonPushed, true);
            app.GenerateBand10AnalysisButton.Visible = 'off';
            app.GenerateBand10AnalysisButton.Text = 'Generate Band10 Analysis';
            app.GenerateResamplingAnalysisButton = uibutton(app.GridLayout2, 'push');
            app.GenerateResamplingAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateResamplingAnalysisButtonPushed, true);
            app.GenerateResamplingAnalysisButton.Text = 'Generate Resampling Analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end